classdef Vocal_Changer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Switch_Label               matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Panel3                     matlab.ui.container.Panel
        PitchShiftCheckBox         matlab.ui.control.CheckBox
        FlangerCheckBox            matlab.ui.control.CheckBox
        EchoCheckBox               matlab.ui.control.CheckBox
        Panel2                     matlab.ui.container.Panel
        CheckBox2                  matlab.ui.control.CheckBox
        Label3                     matlab.ui.control.Label
        NumericEditField4          matlab.ui.control.NumericEditField
        Label2                     matlab.ui.control.Label
        NumericEditField3          matlab.ui.control.NumericEditField
        LabelNumericEditField3     matlab.ui.control.Label
        CheckBox                   matlab.ui.control.CheckBox
        Panel                      matlab.ui.container.Panel
        NumericEditField_2         matlab.ui.control.NumericEditField
        PassbandFrequency1Label_2  matlab.ui.control.Label
        NumericEditField2_2        matlab.ui.control.NumericEditField
        PassbandFrequency2Label    matlab.ui.control.Label
        AttenuationSlider          matlab.ui.control.Slider
        AttenuationSliderLabel     matlab.ui.control.Label
        NumericEditField2          matlab.ui.control.NumericEditField
        StopbandFrequency2Label    matlab.ui.control.Label
        NumericEditField           matlab.ui.control.NumericEditField
        StopbandFrequency1Label_2  matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        RadioButton4               matlab.ui.control.RadioButton
        RadioButton3               matlab.ui.control.RadioButton
        RadioButton2               matlab.ui.control.RadioButton
        RadioButton                matlab.ui.control.RadioButton
        Button                     matlab.ui.control.Button
    end


    properties (Access = private)
        % Share input data across the app 
        
        Y               % data from the original track
        FS              % fs of the og track
        Frequency1      % stopband frequency
        Frequency2      %   -//-
        Time1           % time selection for the filtering function
        Time2           %   -//-
        File            % filename
        PassFreq1       % passband frequency
        PassFreq2       %   -//-
        Attenuation     % attenuation for the filtering function
        Panel2Vis       % counter to show panel
        background      % background track after separation 
        vocals          % vocals track after separation
        
        
    end

    methods (Access = private)
        
%         function filtered = filtering(~,Fs, y1, f1, f2)
%             Fn = Fs/2;      % Nyguist Frequency
%             F1 = f1/Fn;     % Normalize frequencies 
%             F2 = f2/Fn;
%             
%             d = fir1(300,[F1 F2],'stop'); % Design Filter 
%             filtered = filter(d,1,y1);    % Apply filter
%             
%             %figure(1)
%             %freqz(d,1,2048,Fs)            % Frequency response of filter 
%             
%        end
        
        function [bsFilt,filtered] = filtering(~,Fs, y1, f1, f2, pass1, pass2,Att)
            
            bsFilt = designfilt('bandstopfir',...   % Filter Specifications 
           'PassbandFrequency1',pass1, ...    
           'StopbandFrequency1',f1, ...
           'StopbandFrequency2',f2, ...
           'PassbandFrequency2',pass2, ...
           'StopbandAttenuation',Att,'SampleRate',Fs); 
            
            filtered = filter(bsFilt,y1);           % Apply Filter
           
           
        end
        
      
        function waitbar(~)
            fig = uifigure;
            bar = uiprogressdlg(fig,'Title','Please Wait');
            
            % Loading file
            % ...
            bar.Value = .33; 
            bar.Message = 'Loading file';
            pause(1.25)   
            
            % Editing
            % ...
            bar.Value = .66; 
            bar.Message = 'Editing';
            pause(1.35)   
            
            
            % Creating destination file
            % ...
            bar.Value = .90; 
            bar.Message = 'Creating destination file';
            pause(1.25)  
        
            % Close dialog box
            close(bar)
            close(fig)
        end
        
        function [s1,s3] = separate(~,audio_in,audio_freq_samp1)
             
            %ALLIGNING THE VALUES TO LENGTH OF AUDIO, AND DF IS THE MINIMUM FREQUENCY RANGE
            length_audio = length(audio_in);
            df = audio_freq_samp1/length_audio;
             
            %CALCULATING FREQUENCY VALUES TO BE ASSIGNED ON THE X-AXIS OF THE GRAPH
            frequency_audio = -audio_freq_samp1/2:df:audio_freq_samp1/2-df;
             
            %BY APPLYING FOURIER TRANSFORM TO THE AUDIO FILE
            FFT_audio_in = fftshift(fft(audio_in)/length(fft(audio_in)));

            %SEPARATE THE VARIOUS COMPONENTS BY CUTTING IT IN FREQUENCY RANGE
            lower_threshold = 800;
            upper_threshold = 5500;
             
            % WHEN THE VALUES IN THE ARRAY ARE IN THE FREQUENCY RANGE THEN WE HAVE 1 AT
            % THAT INDEX AND O FOR OTHERS I.E; CREATING AN BOOLEAN INDEX ARRAY
             
            val = abs(frequency_audio)<upper_threshold & abs(frequency_audio)>lower_threshold;
            FFT_ins = FFT_audio_in(:,1);
            FFT_voc = FFT_audio_in(:,1);
            %BY THE LOGICAL ARRAY THE FOURIER IN FREQUENCY RANGE IS KEPT IN VOCALS;AND
            %REST IN INSTRUMENTAL AND REST OF THE VALUES TO ZERO
            FFT_ins(val) = 0;
            FFT_voc(~val) = 0;
             
            %NOW WE PERFORM THE INVERSE FOURIER TRANSFORM TO GET BACK THE SIGNAL
            FFT_a = ifftshift(FFT_audio_in);
            FFT_a11 = ifftshift(FFT_ins);
            FFT_a31 = ifftshift(FFT_voc);
             
            %CREATING THE TIME DOMAIN SIGNAL
            s1 = ifft(FFT_a11*length(fft(audio_in)));  
            s3 = ifft(FFT_a31*length(fft(audio_in)));
            
            %WRITING THE FILE
            audiowrite('sound_background.wav',s1,audio_freq_samp1);
            audiowrite('sound_voice.wav',s3,audio_freq_samp1);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Panel.Visible = 'off';
           app.Panel2.Visible = 'off';
           app.Panel3.Visible = 'off';
           app.ButtonGroup.Visible = 'off';
           
           app.Switch.Visible = 'off';
           app.Switch_Label.Visible = 'off';
           
           app.Panel2Vis = 0;
           
           app.RadioButton3.Visible = 'off';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [File,path] = uigetfile('*.wav'); % open a wav file
           
            [y,fs] = audioread(strcat(path,File));        % store input data
            
            x = size(y);             % get dimensions of array
            z = x(1,2);              % check for columns       
            app.Y = y;               % pass variables 
            app.FS = fs;             % from app
               
            
            app.ButtonGroup.Visible = 'on';
            
            app.File = File;
            if z == 1           % test if the song is stereo
                errordlg('Try another file in stereo','File Error') % error message 
            end
         
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
          %  selectedButton = app.ButtonGroup.SelectedObject;
          if app.RadioButton2.Value == true        % if user selects button, show next panel
              app.Panel.Visible = 'on';        
          else
               app.Panel.Visible = 'off';
          end
          
          if app.RadioButton4.Value == true        % if user selects button, show next panel
              app.Panel3.Visible = 'on';     
              waitbar(app)
              [back,voice]=separate(app,app.Y,app.FS);   % call separate function 
              app.background = back;                     % pass background track to the app
              app.vocals = voice;                        % pass vocals track to the app
              
          else
               app.Panel3.Visible = 'off';
          end     
            
          if app.RadioButton.Value == true
              waitbar(app)
              left = app.Y(:,1);                % seperate each channel
              right = app.Y(:,2);
             % soundsc(left-right,app.FS)
             unvoiced = left - right;            % remove the common part from both channels
            %  file = uiputfile('*.wav');   
             audiowrite(strcat("[VOCALS REMOVED] - ",app.File),unvoiced,app.FS)    % write wav file 
             
          end
          
        end

        % Value changed function: NumericEditField3
        function NumericEditField3ValueChanged(app, event)
            app.Time1 = app.NumericEditField3.Value;    % time selection for separate function
            
        end

        % Value changed function: NumericEditField
        function NumericEditFieldValueChanged(app, event)
            frequency1 = app.NumericEditField.Value;
            app.Frequency1 = frequency1;        % stopband frequency 1
            x = exist('frequency');             % if user entered a value show next panel
            if x == 1
                app.Panel2Vis = app.Panel2Vis + 1;  % calculate counter for pane visibility 
            end
             if app.Panel2Vis == 3
                app.Panel2.Visible = 'on';
                app.Switch.Visible = 'on';
             end
        end

        % Value changed function: NumericEditField2
        function NumericEditField2ValueChanged(app, event)
            frequency2 = app.NumericEditField2.Value;
            app.Frequency2 = frequency2;        % stopband frequency 2
            y = exist('frequency2');            % if user entered a value show next panel
            if y == 1
                app.Panel2Vis = app.Panel2Vis + 1;  % calculate counter for pane visibility 
            end
             if app.Panel2Vis == 3
                app.Panel2.Visible = 'on';
                app.Switch.Visible = 'on';
             end
        end

        % Value changed function: CheckBox
        function wholesongbox(app, event)
            value = app.CheckBox.Value;
            waitbar(app)
            if value == true
                %clean = filtering(app,app.FS,app.Y,app.Frequency1,app.Frequency2);  % filter the song
                [~,clean] = filtering(app,app.FS,app.Y,app.Frequency1,app.Frequency2,app.PassFreq1,app.PassFreq2,app.Attenuation);
                audiowrite(strcat("[FILTERED] ",app.File),clean,app.FS)    % write wav file 
            end
        end

        % Value changed function: CheckBox2
        function specificpartbox(app, event)
            value = app.CheckBox2.Value;
            waitbar(app)
            if value == true 
               x1 = app.Time1 * app.FS;        % convert time to array position
               x2 = app.Time2 * app.FS;
               ynew = app.Y(x1:x2,:);          % select the part of the song to get filtered
               
               %clean = filtering(app,app.FS,ynew,app.Frequency1,app.Frequency2);  % filter the song
               [~,clean] = filtering(app,app.FS,ynew,app.Frequency1,app.Frequency2,app.PassFreq1,app.PassFreq2,app.Attenuation);
               y1 = app.Y(1:x1,:);                % select the beginning part of the song 
               y2 = app.Y(x2:end,:);              % select the ending part of the song
               ylast = vertcat(y1,clean,y2);      % combine the three parts 
               
                %file = uiputfile('*.wav');   
               audiowrite(strcat("[PARTLY FILTERED] ",app.File),ylast,app.FS)    % write wav file 
            end
        end

        % Value changed function: NumericEditField4
        function NumericEditField4ValueChanged(app, event)
            app.Time2 = app.NumericEditField4.Value;    % time selection for separate function
            
        end

        % Value changed function: NumericEditField_2
        function NumericEditField_2ValueChanged(app, event)
             pass1 = app.NumericEditField_2.Value;   % passband frequency 1
             app.PassFreq1 = pass1;
             
            y = exist('pass1');                      % if user entered a value show next panel
            if y == 1
                app.Panel2Vis = app.Panel2Vis + 1;   % calculate counter for pane visibility 
            end
             if app.Panel2Vis == 3
                app.Panel2.Visible = 'on';
                app.Switch.Visible = 'on';
            end
            
            
        end

        % Value changed function: NumericEditField2_2
        function NumericEditField2_2ValueChanged(app, event)
             pass2 = app.NumericEditField2_2.Value; % passband frequency 2
             app.PassFreq2 = pass2;
             
            y = exist('pass2');                     % if user entered a value show next panel
            if y == 1
                app.Panel2Vis = app.Panel2Vis + 1;  % calculate counter for pane visibility 
            end
            if app.Panel2Vis == 3
                app.Panel2.Visible = 'on';
                app.Switch.Visible = 'on';
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switched = app.Switch.Value;    
            
            if strcmp(switched,'On')
                
                [b,clean] = filtering(app,app.FS,app.Y,app.Frequency1,app.Frequency2,app.PassFreq1,app.PassFreq2,app.Attenuation); % use filtering function to apply custom filter to the song
                
                signal_length = length(app.Y); 
                
                figure(1);
                subplot(2,1,1);
                x=abs(fft(app.Y(:,1))); % fft of original track
                f_axis=[0:signal_length-1]*app.FS/signal_length; %convert fft plot from bins to HZ
                plot(f_axis,x);     % plot the original track
                title('Inital Song');
                ylabel 'Amplitude';
                xlabel 'Frequency (Hz)';
                
                subplot(2,1,2)
                x_clean = abs(fft(clean(:,1))); % fft of filtered track
                plot(f_axis,x_clean);     % plot the track after it had been filtered 
                title('Filtered Song');
                ylabel 'Amplitude';
                xlabel 'Frequency (Hz)';
                
                fvtool(b);                      % plot filter specifications 
            else 
                
            end
        end

        % Value changed function: AttenuationSlider
        function AttenuationSliderValueChanged(app, event)
            Att = app.AttenuationSlider.Value;      % attenuation for filtering function
            app.Attenuation = Att;
        end

        % Value changed function: EchoCheckBox
        function EchoCheckBoxValueChanged(app, event)
            value = app.EchoCheckBox.Value;
            
            if value == true
                waitbar(app)
                x = app.vocals;          % pass variables 
                fs = app.FS;             % from app 
                length = size(x);        % get the length of the music file
                a = 0.35;                 % set the attenuation factor
                delay = 0.45;
                D = delay*fs;            % set the delay time in s
                y = zeros(length(1),2);  % initialize the output music signal
                
                for i = D + 1 : 1 : length(1)
                    %add the delay of the original signal to both of channels
                	y(i) = x(i) + a*x(i-D);
                    y(i,2) = x(i) + a*x(i-D);
                end
                output = (y + app.background)/2;  % merge vocals with effect with the original song
                
                audiowrite(strcat("[Echo applied] ",app.File),output,fs);
            end
        end

        % Value changed function: FlangerCheckBox
        function FlangerCheckBoxValueChanged(app, event)
            value = app.FlangerCheckBox.Value;
            
            if value == true
                waitbar(app)
                x = app.vocals;    % pass variables 
                fs = app.FS;       % from app 
                
                delay = 15;         
                range = 12;           % length of the effect
                sweep_freq = 12;      % changing rate of delay
                length=numel(x(:,1)); % keep only the number of rows  
                
                for i = 1:length-delay-range
                    %add a dynamic delay to the original signal
                    y(i) = x(i) + x(i+delay+round(range*sin(2*pi*i*sweep_freq/fs)));
                end
                y = transpose(y);
                app.background((size(y)+1):size(app.background)) = []; %resize the array to have the same size with the new array
                output = (y + app.background)/2;                       % merge vocals with effect and the original song
                
                audiowrite(strcat("[Flanger applied] ",app.File),output,fs);
                                
            end
            
        end

        % Value changed function: PitchShiftCheckBox
        function PitchShiftCheckBoxValueChanged(app, event)
            value = app.PitchShiftCheckBox.Value;
            
            if value == true
                waitbar(app)
                x = app.Y;      % pass variables 
                fs = app.FS;    % from app
                
                nsemitones = -4;
                output = shiftPitch(x,nsemitones); % lower the original signal by 4 semitones
                
                audiowrite(strcat("[Pitch-shift applied] ",app.File),output,fs);
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Select song (stereo)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'What would you want to apply?';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Remove vocals';
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Lower frequencies';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Text = 'nothere';
            app.RadioButton3.Value = true;
            app.RadioButton4 = uiradiobutton(app.ButtonGroup);
            app.RadioButton4.Text = 'Separate vocals to change them';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Design Filter';
            app.StopbandFrequency1Label_2 = uilabel(app.Panel);
            app.StopbandFrequency1Label_2.Text = 'StopbandFrequency1';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @NumericEditFieldValueChanged, true);
            app.StopbandFrequency2Label = uilabel(app.Panel);
            app.StopbandFrequency2Label.Text = 'StopbandFrequency2';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2.ValueChangedFcn = createCallbackFcn(app, @NumericEditField2ValueChanged, true);
            app.AttenuationSliderLabel = uilabel(app.Panel);
            app.AttenuationSliderLabel.Text = 'Attenuation';
            app.AttenuationSlider = uislider(app.Panel);
            app.AttenuationSlider.Limits = [40 80];
            app.AttenuationSlider.ValueChangedFcn = createCallbackFcn(app, @AttenuationSliderValueChanged, true);
            app.AttenuationSlider.Value = 40;
            app.PassbandFrequency2Label = uilabel(app.Panel);
            app.PassbandFrequency2Label.Text = 'PassbandFrequency2';
            app.NumericEditField2_2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2_2.ValueChangedFcn = createCallbackFcn(app, @NumericEditField2_2ValueChanged, true);
            app.PassbandFrequency1Label_2 = uilabel(app.Panel);
            app.PassbandFrequency1Label_2.Text = 'PassbandFrequency1';
            app.NumericEditField_2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumericEditField_2ValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Select time';
            app.CheckBox = uicheckbox(app.Panel2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @wholesongbox, true);
            app.CheckBox.Text = 'Select the whole song';
            app.LabelNumericEditField3 = uilabel(app.Panel2);
            app.LabelNumericEditField3.Text = 'Select starting time in secs';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @NumericEditField3ValueChanged, true);
            app.Label2 = uilabel(app.Panel2);
            app.Label2.Text = 'Select ending time in secs';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @NumericEditField4ValueChanged, true);
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'OR';
            app.CheckBox2 = uicheckbox(app.Panel2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @specificpartbox, true);
            app.CheckBox2.Text = 'Apply to specific part of the song';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Select Voice Effect ';
            app.EchoCheckBox = uicheckbox(app.Panel3);
            app.EchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EchoCheckBoxValueChanged, true);
            app.EchoCheckBox.Text = 'Echo';
            app.FlangerCheckBox = uicheckbox(app.Panel3);
            app.FlangerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlangerCheckBoxValueChanged, true);
            app.FlangerCheckBox.Text = 'Flanger';
            app.PitchShiftCheckBox = uicheckbox(app.Panel3);
            app.PitchShiftCheckBox.ValueChangedFcn = createCallbackFcn(app, @PitchShiftCheckBoxValueChanged, true);
            app.PitchShiftCheckBox.Text = 'Pitch-Shift';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch_Label = uilabel(app.UIFigure);
            app.Switch_Label.Text = 'Show the difference between wav files  ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vocal_Changer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end